classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PhantomDropDownLabel            matlab.ui.control.Label
        PhantomDropDown                 matlab.ui.control.DropDown
        AnglestepnumberEditFieldLabel   matlab.ui.control.Label
        AnglestepnumberEditField        matlab.ui.control.NumericEditField
        ProjectionnumberEditFieldLabel  matlab.ui.control.Label
        ProjectionnumberEditField       matlab.ui.control.NumericEditField
        GetProjectionButton             matlab.ui.control.Button
        AngledegreesEditFieldLabel      matlab.ui.control.Label
        AngledegreesEditField           matlab.ui.control.NumericEditField
        ShowprojectionButton            matlab.ui.control.Button
        ProjectionLabel                 matlab.ui.control.Label
        BackprojectionLabel             matlab.ui.control.Label
        FiltertypeDropDownLabel         matlab.ui.control.Label
        FiltertypeDropDown              matlab.ui.control.DropDown
        ShowradonButton                 matlab.ui.control.Button
        BackprojectButton               matlab.ui.control.Button
        BackprojectradonButton          matlab.ui.control.Button
        ShowinputButton                 matlab.ui.control.Button
        SNRdBTextAreaLabel              matlab.ui.control.Label
        SNRdBTextArea                   matlab.ui.control.TextArea
        SNRButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        manualData
        radonData
        M
        originalImage
        backImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetProjectionButton
        function GetProjectionButtonPushed(app, event)
            if(app.PhantomDropDown.Value == "Brain")
                input = load("images/brain.mat").brain; %input file is loaded
                
            elseif(app.PhantomDropDown.Value == "SheppLogan")
                input = load("images/SheppLogan.mat").SheppLogan;
                
            elseif(app.PhantomDropDown.Value == "Square")
                input = load("images/square.mat").square;
                
            elseif(app.PhantomDropDown.Value == "Square circle")
                input = load("images/square_circle.mat").square_circle;
                
            elseif(app.PhantomDropDown.Value == "User input")
                input = load("images/phantom.mat");
            end

            angleStepNumber = app.AnglestepnumberEditField.Value; %number of angle steps in [0,180)
            projectionNumber = app.ProjectionnumberEditField.Value; %number of t steps
            
            M = length(input)/2; %Image dimension, assuming square input
            projectionData = zeros(angleStepNumber,projectionNumber,3);
            angles = linspace(1e-5,pi-1e-5,angleStepNumber); %For multiple angles
     
            for i=1:length(angles) %for all theta
                theta = angles(i);
                %tMax = M*(abs(cos(theta)) + abs(sin(theta)))-1e-10; %|t| <= M(|cos|+|sin|)
                tMax = M*sqrt(2);
                tVect = linspace(-tMax,tMax,projectionNumber);
                for j=1:length(tVect) %for all t
                    t = tVect(j);
                    points = findInt(theta,t,M); %integer crossing points for some theta,t
                    projectionData(i,j,1) = lineIntegral(input,points,M); %projection projectionData is stored in projectionData array
                    projectionData(i,j,2) = theta; %corresponding parameters are also stored
                    projectionData(i,j,3) = t;
                end
            end

            [radonp,radont] = radon(input,angles*180/pi);
            projectionNumber = length(radonp(:,1)); %Create radon projection projectionData
            radonData1 = zeros(angleStepNumber,projectionNumber,3);
            radonData1(:,:,1) = radonp';
            radonData1(:,:,2) = repmat(angles',1,projectionNumber);
            radonData1(:,:,3) = repmat(radont',angleStepNumber,1);
            
            app.manualData = projectionData;
            app.radonData = radonData1;
            app.M = M;
            app.originalImage = input;
        end

        % Button pushed function: ShowprojectionButton
        function ShowprojectionButtonPushed(app, event)
            if(app.PhantomDropDown.Value == "Brain")
                input = load("images/brain.mat").brain; %input file is loaded
                
            elseif(app.PhantomDropDown.Value == "SheppLogan")
                input = load("images/SheppLogan.mat").SheppLogan;
                
            elseif(app.PhantomDropDown.Value == "Square")
                input = load("images/square.mat").square;
                
            elseif(app.PhantomDropDown.Value == "Square circle")
                input = load("images/square_circle.mat").square_circle;
                
            elseif(app.PhantomDropDown.Value == "User input")
                input = load("images/phantom.mat");
            end

            angleStepNumber = app.AnglestepnumberEditField.Value; %number of angle steps in [0,180)
            projectionNumber = app.ProjectionnumberEditField.Value; %number of t steps
            
            M = length(input)/2; %Image dimension, assuming square input
            projectionData = zeros(angleStepNumber,projectionNumber,3);
            angles = app.AngledegreesEditField.Value*pi/180;
     
            for i=1:length(angles) %for all theta
                theta = angles(i);
                %tMax = M*(abs(cos(theta)) + abs(sin(theta)))-1e-10; %|t| <= M(|cos|+|sin|)
                tMax = M*sqrt(2);
                tVect = linspace(-tMax,tMax,projectionNumber);
                for j=1:length(tVect) %for all t
                    t = tVect(j);
                    points = findInt(theta,t,M); %integer crossing points for some theta,t
                    projectionData(i,j,1) = lineIntegral(input,points,M); %projection projectionData is stored in projectionData array
                    projectionData(i,j,2) = theta; %corresponding parameters are also stored
                    projectionData(i,j,3) = t;
                end
            end
            figure();
            plot(projectionData(1,:,3),projectionData(1,:,1))
        end

        % Button pushed function: ShowradonButton
        function ShowradonButtonPushed(app, event)
            if(app.PhantomDropDown.Value == "Brain")
                input = load("images/brain.mat").brain; %input file is loaded
                
            elseif(app.PhantomDropDown.Value == "SheppLogan")
                input = load("images/SheppLogan.mat").SheppLogan;
                
            elseif(app.PhantomDropDown.Value == "Square")
                input = load("images/square.mat").square;
                
            elseif(app.PhantomDropDown.Value == "Square circle")
                input = load("images/square_circle.mat").square_circle;
                
            elseif(app.PhantomDropDown.Value == "User input")
                input = load("images/brain.mat");
            end
            
            [radonp,radont] = radon(input,app.AngledegreesEditField.Value);
            figure();
            plot(radont,radonp)
        end

        % Button pushed function: BackprojectButton
        function BackprojectButtonPushed(app, event)
        input = app.manualData; %radonData or projectionData should be chosen.
        backData = zeros(2*app.M,2*app.M); %Initialization of the image.
        angles = input(:,1,2); %Every row of input corresponds to a different angle.
                               %Second plane contains angle values.
        
        samp = length(input(1,:,3)); %Length of rows gives the length of projections.
                                     %Third plane contains t values.                       
                                                                         
        %Filter
        s = linspace(-0.5,0.5,samp)';
        ham = 0.54 - 0.46*cos(2*pi*s);
        cosi = cos(pi*s);
        
        
        %Choose desired filter type
        if(app.FiltertypeDropDown.Value == "No filter")
            H = ones(1,samp)';

        elseif(app.FiltertypeDropDown.Value == "Ram-Lak")
            H = triang(samp); %Ram-lak

        elseif(app.FiltertypeDropDown.Value == "Hamming")
            H = ham.*triang(samp); %Hamming

        elseif(app.FiltertypeDropDown.Value == "SheppLogan")
            H = fftshift(sinc(s)).*triang(samp); %SheppLogan

        elseif(app.FiltertypeDropDown.Value == "Cosine")
            H = fftshift(cosi).*triang(samp);

        end        
        
        
        filter = repmat(H,1,length(angles))';
        fftInput = fft(input(:,:,1),[],2); %FFT for every projection.
        filteredFFT = fftInput.*filter; %Filtering in fourier domain
        filt = ifft(filteredFFT,[],2); %Return to t domain.
        input(:,:,1) = real(filt); %Very small imaginary parts are removed.


        %% Backproject
        for i=1:length(angles)
            t = input(i,:,3); %Get all t values for every theta.
            for j=1:length(t)
                points = findInt(angles(i),t(j),app.M); %Find integer crossing points.
                segmentSum = backIntegral(points,input(i,j,1),app.M); %Get positions
                                                         %that are to be updated
                if isempty(segmentSum) %Continue if no update is required.
                    continue;
                end
                for k=1:length(segmentSum(:,1))
                    y = segmentSum(k,1);
                    x = segmentSum(k,2);
                    backData(y,x) = backData(y,x) + segmentSum(k,3); %Add data
                                                      %to corresponding locations.
                end
            end    
        end
        backData(app.M,app.M) = 0; %Middle pixel has problems, this pixel is discarded.
        app.backImage = backData;
        
        figure();
        imagesc(mat2gray(backData))
        colormap gray
        colorbar
        end

        % Button pushed function: BackprojectradonButton
        function BackprojectradonButtonPushed(app, event)
        input = app.radonData; %radonData or projectionData should be chosen.
        backData = zeros(2*app.M,2*app.M); %Initialization of the image.
        angles = input(:,1,2); %Every row of input corresponds to a different angle.
                               %Second plane contains angle values.
        
        samp = length(input(1,:,3)); %Length of rows gives the length of projections.
                                     %Third plane contains t values.                       
                                                                         
        %Filter
        s = linspace(-0.5,0.5,samp)';
        ham = 0.54 - 0.46*cos(2*pi*s);
        cosi = cos(pi*s);
        
        
        %Choose desired filter type
        if(app.FiltertypeDropDown.Value == "No filter")
            H = ones(1,samp)';
        elseif(app.FiltertypeDropDown.Value == "Ram-Lak")
            H = triang(samp); %Ram-lak

        elseif(app.FiltertypeDropDown.Value == "Hamming")
            H = ham.*triang(samp); %Hamming

        elseif(app.FiltertypeDropDown.Value == "SheppLogan")
            H = fftshift(sinc(s)).*triang(samp); %SheppLogan

        elseif(app.FiltertypeDropDown.Value == "Cosine")
            H = fftshift(cosi).*triang(samp);
        end        
        
        
        filter = repmat(H,1,length(angles))';
        fftInput = fft(input(:,:,1),[],2); %FFT for every projection.
        filteredFFT = fftInput.*filter; %Filtering in fourier domain
        filt = ifft(filteredFFT,[],2); %Return to t domain.
        input(:,:,1) = real(filt); %Very small imaginary parts are removed.


        %% Backproject
        for i=1:length(angles)
            t = input(i,:,3); %Get all t values for every theta.
            for j=1:length(t)
                points = findInt(angles(i),t(j),app.M); %Find integer crossing points.
                segmentSum = backIntegral(points,input(i,j,1),app.M); %Get positions
                                                         %that are to be updated
                if isempty(segmentSum) %Continue if no update is required.
                    continue;
                end
                for k=1:length(segmentSum(:,1))
                    y = segmentSum(k,1);
                    x = segmentSum(k,2);
                    backData(y,x) = backData(y,x) + segmentSum(k,3); %Add data
                                                      %to corresponding locations.
                end
            end    
        end
        backData(app.M,app.M) = 0; %Middle pixel has problems, this pixel is discarded.
        app.backImage = backData;
        
        figure();
        imagesc(backData)
        colormap gray
        colorbar
        end

        % Button pushed function: ShowinputButton
        function ShowinputButtonPushed(app, event)
            figure();
            imagesc(mat2gray(app.originalImage))
            colormap gray
            colorbar
        end

        % Button pushed function: SNRButton
        function SNRButtonPushed(app, event)
            t = mat2gray(app.originalImage);
            r = mat2gray(app.backImage);
            t(app.M,app.M) = 0;

            sum1 = 0;
            sum2 = 0;
            for i=1:length(t)
                for j = 1:length(t)
                    sum1 = sum1 + r(i,j)^2;
                    sum2 = sum2 + abs((t(i,j)-r(i,j)))^2;
                end
            end
            app.SNRdBTextArea.Value = num2str(10*log10(sum1/sum2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PhantomDropDownLabel = uilabel(app.UIFigure);
            app.PhantomDropDownLabel.Text = 'Phantom';
            app.PhantomDropDown = uidropdown(app.UIFigure);
            app.PhantomDropDown.Items = {'Brain', 'SheppLogan', 'Square', 'Square circle', 'User input'};
            app.PhantomDropDown.Value = 'Brain';
            app.AnglestepnumberEditFieldLabel = uilabel(app.UIFigure);
            app.AnglestepnumberEditFieldLabel.Text = 'Angle step number';
            app.AnglestepnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnglestepnumberEditField.Value = 180;
            app.ProjectionnumberEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectionnumberEditFieldLabel.Text = 'Projection number';
            app.ProjectionnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectionnumberEditField.Value = 367;
            app.GetProjectionButton = uibutton(app.UIFigure, 'push');
            app.GetProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @GetProjectionButtonPushed, true);
            app.GetProjectionButton.Text = 'Get Projection';
            app.AngledegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegreesEditFieldLabel.Text = 'Angle (degrees)';
            app.AngledegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegreesEditField.Value = 45;
            app.ShowprojectionButton = uibutton(app.UIFigure, 'push');
            app.ShowprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowprojectionButtonPushed, true);
            app.ShowprojectionButton.Text = 'Show projection';
            app.ProjectionLabel = uilabel(app.UIFigure);
            app.ProjectionLabel.Text = 'Projection';
            app.BackprojectionLabel = uilabel(app.UIFigure);
            app.BackprojectionLabel.Text = 'Back projection';
            app.FiltertypeDropDownLabel = uilabel(app.UIFigure);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.FiltertypeDropDown = uidropdown(app.UIFigure);
            app.FiltertypeDropDown.Items = {'No filter', 'Ram-Lak', 'Hamming', 'SheppLogan', 'Cosine'};
            app.FiltertypeDropDown.Value = 'No filter';
            app.ShowradonButton = uibutton(app.UIFigure, 'push');
            app.ShowradonButton.ButtonPushedFcn = createCallbackFcn(app, @ShowradonButtonPushed, true);
            app.ShowradonButton.Text = 'Show radon';
            app.BackprojectButton = uibutton(app.UIFigure, 'push');
            app.BackprojectButton.ButtonPushedFcn = createCallbackFcn(app, @BackprojectButtonPushed, true);
            app.BackprojectButton.Text = {'Backproject'; ''};
            app.BackprojectradonButton = uibutton(app.UIFigure, 'push');
            app.BackprojectradonButton.ButtonPushedFcn = createCallbackFcn(app, @BackprojectradonButtonPushed, true);
            app.BackprojectradonButton.Text = 'Backproject radon';
            app.ShowinputButton = uibutton(app.UIFigure, 'push');
            app.ShowinputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinputButtonPushed, true);
            app.ShowinputButton.Text = 'Show input';
            app.SNRdBTextAreaLabel = uilabel(app.UIFigure);
            app.SNRdBTextAreaLabel.Text = 'SNR (dB)';
            app.SNRdBTextArea = uitextarea(app.UIFigure);
            app.SNRButton = uibutton(app.UIFigure, 'push');
            app.SNRButton.ButtonPushedFcn = createCallbackFcn(app, @SNRButtonPushed, true);
            app.SNRButton.Text = 'SNR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end